classdef AppCurrentVersion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        FlamingSunDropDown        matlab.ui.control.DropDown
        FlamingSunDropDownLabel   matlab.ui.control.Label
        TurnLabel                 matlab.ui.control.Label
        OpponentsScoreGauge       matlab.ui.control.LinearGauge
        OpponentsScoreGaugeLabel  matlab.ui.control.Label
        YourScoreGauge            matlab.ui.control.LinearGauge
        YourScoreGaugeLabel       matlab.ui.control.Label
        YourOpponentsDiceLabel    matlab.ui.control.Label
        Image_10                  matlab.ui.control.Image
        Image_9                   matlab.ui.control.Image
        Image_8                   matlab.ui.control.Image
        Image_7                   matlab.ui.control.Image
        Image_6                   matlab.ui.control.Image
        Image_5                   matlab.ui.control.Image
        Image_4                   matlab.ui.control.Image
        Image_3                   matlab.ui.control.Image
        Image_2                   matlab.ui.control.Image
        Image                     matlab.ui.control.Image
        Gauge_5                   matlab.ui.control.Gauge
        Gauge_5Label              matlab.ui.control.Label
        Gauge_4                   matlab.ui.control.Gauge
        Gauge_4Label              matlab.ui.control.Label
        Gauge_3                   matlab.ui.control.Gauge
        Gauge_3Label              matlab.ui.control.Label
        Gauge_2                   matlab.ui.control.Gauge
        Gauge_2Label              matlab.ui.control.Label
        Gauge                     matlab.ui.control.Gauge
        GaugeLabel                matlab.ui.control.Label
        RollthediceButton         matlab.ui.control.Button
        LockValueButton_5         matlab.ui.control.StateButton
        LockValueButton_4         matlab.ui.control.StateButton
        LockValueButton_3         matlab.ui.control.StateButton
        LockValueButton_2         matlab.ui.control.StateButton
        LockValueButton           matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        d1val;
        d2val;
        d3val;
        d4val;
        d5val;
        d6val;
        d7val;
        d8val;
        d9val;
        d10val;
        flamesun;
        yourScore;
        oppsScore;
        currentPlayerIsYou = true;  
        hasEnteredGameYou = false;
        hasEnteredGameOpp = false;
    end

    methods (Access = public)
        
        function updateDiceImages(app)
            disp(app.d1val);
            app.Image.ImageSource = app.chooseImage(app.d1val);
            app.Image_2.ImageSource = app.chooseImage(app.d2val);
            app.Image_3.ImageSource = app.chooseImage(app.d3val);
            app.Image_4.ImageSource = app.chooseImage(app.d4val);
            app.Image_5.ImageSource = app.chooseImage(app.d5val);
        end


        function imgSource = chooseImage(~,dVal)
            folder = fullfile(fileparts(mfilename('fullpath')), "Images");
            switch dVal
                case 1
                    imgSource = fullfile(folder, "halfmoons.gif");
                case 2
                    imgSource = fullfile(folder, "pyramids.gif");
                case 3
                    imgSource = fullfile(folder, "starburst.gif");
                case 4
                    imgSource = fullfile(folder, "five.gif");
                case 5
                    imgSource = fullfile(folder, "stars.gif");
                case 6
                    imgSource = fullfile(folder, "ten.gif");
                case 6.5
                    imgSource = fullfile(folder, "flamingsun.gif");
            end
        end


        function updateScores(app,yourScore,oppsScore)
            app.YourScoreGauge.Value = yourScore;
            app.OpponentsScoreGauge.Value = oppsScore;
            app.yourScore = yourScore;
            app.oppsScore = oppsScore;
        end

        function calcScore(app)
            roundStartScore = app.yourScore;
            if (app.d5val == 6.5) && app.FlamingSunDropDown.Visible
                app.waitingForFlamingSunSelection = true;
                return;
            end

           yourDiceArray = [app.d1val, app.d2val, app.d3val, app.d4val, app.d5val];
            
           numVals = zeros(1,6);


            for i = 1:5
                val = yourDiceArray(i);
                if (val >= 1) & (val <=6)
                    numVals(val) = numVals(val) + 1;
                end
            end
            
            if (app.flamesun >= 1) & (app.flamesun <= 6)
                numVals(app.flamesun) = numVals(app.flamesun) + 1;
            end
            
            Flashscore = [20, 30, 40, 50, 60, 100];
            FreightTrainscore = [200, 300, 400, 500, 500, 0];
            
            j=1;
            while(j<=6)
                if(numVals(j) == 3) %Flashes
                    app.yourScore = app.yourScore + Flashscore(j);
                end
                if(numVals(j) == 5) %FreightTrains
                    app.yourScore = app.yourScore + FreightTrainscore(j);
                end
                j=j+1;
            end
            

            if(numVals(4) == 1)
                app.yourScore = app.yourScore + 5;
            end
            if(numVals(4) == 2)
                app.yourScore = app.yourScore + 10;
            end
            if(numVals(4) == 4)
                app.yourScore = app.yourScore + 55;
            end

            if(numVals(6) == 1)
                app.yourScore = app.yourScore + 10;
            end
            if(numVals(6) == 2)
                app.yourScore = app.yourScore + 20;
            end
            if(numVals(6) == 4)
                app.yourScore = app.yourScore + 110;
            end

            app.updateScores(app.yourScore,app.oppsScore)
            disp(app.yourScore)

            roundScore = app.yourScore - roundStartScore;            
            if roundScore > 0
                if app.currentPlayerIsYou
                    if ~app.hasEnteredGameYou
                        if roundScore >= 35
                            app.hasEnteredGameYou = true;
                            app.yourScore = app.yourScore + roundScore;
                        else
                            disp("You didn't enter the game yet! Need at least 35.");
                            return;
                        end
                    else
                        app.yourScore = app.yourScore + roundScore;
                    end
                else
                    if ~app.hasEnteredGameOpp
                        if roundScore >= 35
                            app.hasEnteredGameOpp = true;
                            app.oppsScore = app.oppsScore + roundScore;
                        else
                            disp("Opponent didn't enter the game yet! Need at least 35.");
                            return;
                        end
                    else
                        app.oppsScore = app.oppsScore + roundScore;
                    end
                end
                app.updateScores(app.yourScore, app.oppsScore);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.yourScore = 0;
            app.oppsScore = 0;
            app.TurnLabel.Text = "Your Turn";
        end

        % Button pushed function: RollthediceButton
        function RollthediceButtonPushed(app, event)
            if(app.LockValueButton.Value == false)
                app.d1val = randi(6);
            end
            if(app.LockValueButton_2.Value == false)
                app.d2val = randi(6);
            end
            if(app.LockValueButton_3.Value == false)
                app.d3val = randi(6);
            end
            if(app.LockValueButton_4.Value == false)
                app.d4val = randi(6);
            end
            if(app.LockValueButton_5.Value == false)
                app.d5val = randi(6);
                if(app.d5val == 3)
                    app.d5val = 6.5;
                end
            end
            app.Gauge.Value = app.d1val;
            app.Gauge_2.Value = app.d2val;
            app.Gauge_3.Value = app.d3val;
            app.Gauge_4.Value = app.d4val;
            app.Gauge_5.Value = app.d5val; % instead of a 3 it has a wild
            
            app.updateDiceImages();
            app.calcScore();
            
            app.currentPlayerIsYou = ~app.currentPlayerIsYou;
            if app.currentPlayerIsYou
                app.TurnLabel.Text = "Your Turn";
            else
                app.TurnLabel.Text = "Opponent's Turn";
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LockValueButton = uibutton(app.UIFigure, 'state');
            app.LockValueButton.Text = 'Lock Value';
            app.LockValueButton_2 = uibutton(app.UIFigure, 'state');
            app.LockValueButton_2.Text = 'Lock Value';
            app.LockValueButton_3 = uibutton(app.UIFigure, 'state');
            app.LockValueButton_3.Text = 'Lock Value';
            app.LockValueButton_4 = uibutton(app.UIFigure, 'state');
            app.LockValueButton_4.Text = 'Lock Value';
            app.LockValueButton_5 = uibutton(app.UIFigure, 'state');
            app.LockValueButton_5.Text = 'Lock Value';
            app.RollthediceButton = uibutton(app.UIFigure, 'push');
            app.RollthediceButton.ButtonPushedFcn = createCallbackFcn(app, @RollthediceButtonPushed, true);
            app.RollthediceButton.Text = 'Roll the dice!';
            app.GaugeLabel = uilabel(app.UIFigure);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.UIFigure, 'circular');
            app.Gauge.Limits = [1 6];
            app.Gauge_2Label = uilabel(app.UIFigure);
            app.Gauge_2Label.Text = 'Gauge';
            app.Gauge_2 = uigauge(app.UIFigure, 'circular');
            app.Gauge_2.Limits = [1 6];
            app.Gauge_3Label = uilabel(app.UIFigure);
            app.Gauge_3Label.Text = 'Gauge';
            app.Gauge_3 = uigauge(app.UIFigure, 'circular');
            app.Gauge_3.Limits = [1 6];
            app.Gauge_4Label = uilabel(app.UIFigure);
            app.Gauge_4Label.Text = 'Gauge';
            app.Gauge_4 = uigauge(app.UIFigure, 'circular');
            app.Gauge_4.Limits = [1 6];
            app.Gauge_5Label = uilabel(app.UIFigure);
            app.Gauge_5Label.Text = 'Gauge';
            app.Gauge_5 = uigauge(app.UIFigure, 'circular');
            app.Gauge_5.Limits = [1 7];
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Images', 'halfmoons.gif');
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'Images', 'halfmoons.gif');
            app.Image_3 = uiimage(app.UIFigure);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'Images', 'halfmoons.gif');
            app.Image_4 = uiimage(app.UIFigure);
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'Images', 'halfmoons.gif');
            app.Image_5 = uiimage(app.UIFigure);
            app.Image_5.ImageSource = fullfile(pathToMLAPP, 'Images', 'halfmoons.gif');
            app.Image_6 = uiimage(app.UIFigure);
            app.Image_6.ImageSource = fullfile(pathToMLAPP, 'Images', 'halfmoons.gif');
            app.Image_7 = uiimage(app.UIFigure);
            app.Image_7.ImageSource = fullfile(pathToMLAPP, 'Images', 'halfmoons.gif');
            app.Image_8 = uiimage(app.UIFigure);
            app.Image_8.ImageSource = fullfile(pathToMLAPP, 'Images', 'halfmoons.gif');
            app.Image_9 = uiimage(app.UIFigure);
            app.Image_9.ImageSource = fullfile(pathToMLAPP, 'Images', 'halfmoons.gif');
            app.Image_10 = uiimage(app.UIFigure);
            app.Image_10.ImageSource = fullfile(pathToMLAPP, 'Images', 'halfmoons.gif');
            app.YourOpponentsDiceLabel = uilabel(app.UIFigure);
            app.YourOpponentsDiceLabel.Text = 'Your Opponent''s Dice';
            app.YourScoreGaugeLabel = uilabel(app.UIFigure);
            app.YourScoreGaugeLabel.Text = 'Your Score';
            app.YourScoreGauge = uigauge(app.UIFigure, 'linear');
            app.YourScoreGauge.Limits = [0 500];
            app.OpponentsScoreGaugeLabel = uilabel(app.UIFigure);
            app.OpponentsScoreGaugeLabel.Text = 'Opponent''s Score';
            app.OpponentsScoreGauge = uigauge(app.UIFigure, 'linear');
            app.OpponentsScoreGauge.Limits = [0 500];
            app.TurnLabel = uilabel(app.UIFigure);
            app.TurnLabel.Text = 'Your turn';
            app.FlamingSunDropDownLabel = uilabel(app.UIFigure);
            app.FlamingSunDropDownLabel.Visible = 'off';
            app.FlamingSunDropDownLabel.Text = 'Flaming Sun';
            app.FlamingSunDropDown = uidropdown(app.UIFigure);
            app.FlamingSunDropDown.Items = {'None', 'Moons', 'Triangles', 'Bolts', '5', 'Stars', '10'};
            app.FlamingSunDropDown.Visible = 'off';
            app.FlamingSunDropDown.Value = 'None';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppCurrentVersion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end